classdef ProiectLicenta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        proiectLicenta  matlab.ui.Figure
        settings_Panel  matlab.ui.container.Panel
        layers_Panel    matlab.ui.container.Panel
        tools_Panel     matlab.ui.container.Panel
        actions_Panel   matlab.ui.container.Panel
        export_Button   matlab.ui.control.Button
        import_Button   matlab.ui.control.Button
        canvas_UIAxes   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CanvasImage = Image("") % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function autoScalingOFF(app)
            app.canvas_UIAxes.Toolbar = [];
            disableDefaultInteractivity(app.canvas_UIAxes);
        end

        % Button pushed function: import_Button
        function import_ButtonPushed(app, event)
            try
                [file, path] = uigetfile('*.jpg;*.png;*.bmp');
    
                if isequal(file, 0)
                    disp("Canceled!");
                else
                    fullPath = fullfile(path,file);
                    app.CanvasImage = Image(fullPath);
                    app.CanvasImage.showImage(app.canvas_UIAxes);
                end
            catch Er
                disp('Error: ' + Er.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.proiectLicenta = uifigure('Visible', 'off');
            app.proiectLicenta.Name = 'Spectra';
            app.canvas_UIAxes = uiaxes(app.proiectLicenta);
            title(app.canvas_UIAxes, 'Canvas')
            xlabel(app.canvas_UIAxes, 'X')
            ylabel(app.canvas_UIAxes, 'Y')
            zlabel(app.canvas_UIAxes, 'Z')
            app.actions_Panel = uipanel(app.proiectLicenta);
            app.import_Button = uibutton(app.actions_Panel, 'push');
            app.import_Button.ButtonPushedFcn = createCallbackFcn(app, @import_ButtonPushed, true);
            app.import_Button.Text = 'Import';
            app.export_Button = uibutton(app.actions_Panel, 'push');
            app.export_Button.Text = 'Export';
            app.tools_Panel = uipanel(app.proiectLicenta);
            app.tools_Panel.Title = 'Tools';
            app.layers_Panel = uipanel(app.proiectLicenta);
            app.layers_Panel.Title = 'Layers';
            app.settings_Panel = uipanel(app.proiectLicenta);
            app.settings_Panel.Title = 'Settings';
            % Show the figure after all components are created
            app.proiectLicenta.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProiectLicenta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.proiectLicenta)

            % Execute the startup function
            runStartupFcn(app, @autoScalingOFF)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.proiectLicenta)
        end
    end
end